classdef covid_19final_project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        OptionsButtonGroup         matlab.ui.container.ButtonGroup
        DailyButton                matlab.ui.control.RadioButton
        CumulativeButton           matlab.ui.control.RadioButton
        AveragedofdaysSlider       matlab.ui.control.Slider
        AveragedofdaysSliderLabel  matlab.ui.control.Label
        DatatoplotButtonGroup      matlab.ui.container.ButtonGroup
        BothButton                 matlab.ui.control.RadioButton
        DeathButton                matlab.ui.control.RadioButton
        CasesButton                matlab.ui.control.RadioButton
        StatesListBox              matlab.ui.control.ListBox
        StatesListBoxLabel         matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        CountryListBoxLabel        matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        World ;
        Countries ;
        Dates;
        CurrentPlace;
        AverageDays;
        Average;
    end
    
    methods (Access = private)
        
        function  Sortingcountries(app)
            app.CountryListBox.Items(1) = {app.World.CurrentRegion} ;
            for i = 1 : length(app.World.Children)
            app.CountryListBox.Items(i+1) = {app.World.Children(i).CurrentRegion} ;
            end


        end
        
        function  fillDates(app,covid_data)
            app.Dates = NaT(1,size(covid_data,2)-2) ;
            for i = 3:377
                app.Dates(i-2) = datetime(covid_data{1,i} ,"Format" ,"MM/dd/yy" );
            end

        end
        
        function PlotData(app)

            cla(app.UIAxes,"reset");
            if app.AverageDays > 1  %average slider changed
                if app.CasesButton.Value && app.CumulativeButton.Value

                    bar(app.UIAxes,app.Dates,app.Average.CumCases,'blue');
                    datetick(app.UIAxes,'x',"dd/mm/yy","keeplimits") ;
                    title(app.UIAxes,['Cumulative cases in ',app.CurrentPlace.CurrentRegion,' (',num2str(app.AverageDays),'-day mean)'])

                elseif app.CasesButton.Value && app.DailyButton.Value

                    bar(app.UIAxes,app.Dates,app.Average.DailyCases,'blue');
                    datetick(app.UIAxes,'x',"dd/mm/yy","keeplimits") ;
                    title(app.UIAxes,['Daily cases in ',app.CurrentPlace.CurrentRegion,' (',num2str(app.AverageDays),'-day mean)'])

                elseif app.DeathButton.Value && app.CumulativeButton.Value

                    plot(app.UIAxes,app.Dates,app.Average.CumDeath,'r');
                    datetick(app.UIAxes,'x',"dd/mm/yy","keeplimits") ;
                    title(app.UIAxes,['Cumulative Death in ',app.CurrentPlace.CurrentRegion,' (',num2str(app.AverageDays),'-day mean)'])

                elseif app.DeathButton.Value && app.DailyButton.Value

                    plot(app.UIAxes,app.Dates,app.Average.DailyDeath,'r');
                    datetick(app.UIAxes,'x',"dd/mm/yy","keeplimits") ;
                    title(app.UIAxes,['Daily Death in ',app.CurrentPlace.CurrentRegion,' (',num2str(app.AverageDays),'-day mean)'])

                elseif app.BothButton.Value && app.CumulativeButton.Value

                    yyaxis (app.UIAxes,"left")
                    bar(app.UIAxes,app.Dates,app.Average.CumCases,'blue');
                    
                    yyaxis (app.UIAxes,"right")
                    plot(app.UIAxes,app.Dates,app.Average.CumDeath,'red');
                    datetick(app.UIAxes,'x',"dd/mm/yy","keeplimits") ;
                    title(app.UIAxes,['Both Cumulative cases and Death in ',app.CurrentPlace.CurrentRegion,' (',num2str(app.AverageDays),'-day mean)'])


                elseif app.BothButton.Value && app.DailyButton.Value


                    yyaxis (app.UIAxes,"left")
                    bar(app.UIAxes,app.Dates,app.Average.DailyCases,'blue');

                    yyaxis (app.UIAxes,"right")
                    plot(app.UIAxes,app.Dates,app.Average.DailyDeath,'red');
                    datetick(app.UIAxes,'x',"dd/mm/yy","keeplimits") ;
                    title(app.UIAxes,['Both Daily cases and Death in ',app.CurrentPlace.CurrentRegion,' (',num2str(app.AverageDays),'-day mean)'])
                end

            else
                if app.CasesButton.Value && app.CumulativeButton.Value                    
                    
                    bar(app.UIAxes,app.Dates,app.CurrentPlace.CumCases,'blue');
                    datetick(app.UIAxes,'x',"dd/mm/yy","keeplimits") ;
                    title(app.UIAxes,['Cumulative cases in ',app.CurrentPlace.CurrentRegion])

                elseif app.CasesButton.Value && app.DailyButton.Value
                   
                    bar(app.UIAxes,app.Dates,app.CurrentPlace.DailyCases,'blue');
                    datetick(app.UIAxes,'x',"dd/mm/yy","keeplimits") ;
                    title(app.UIAxes,['Daily cases in ',app.CurrentPlace.CurrentRegion])

                elseif app.DeathButton.Value && app.CumulativeButton.Value
                    
                    plot(app.UIAxes,app.Dates,app.CurrentPlace.CumDeath,'r');
                    datetick(app.UIAxes,'x',"dd/mm/yy","keeplimits") ;
                    title(app.UIAxes,['Cumulative Death in ',app.CurrentPlace.CurrentRegion])

                elseif app.DeathButton.Value && app.DailyButton.Value
                    
                    plot(app.UIAxes,app.Dates,app.CurrentPlace.DailyDeath,'r');
                    datetick(app.UIAxes,'x',"dd/mm/yy","keeplimits") ;
                    title(app.UIAxes,['Daily death in ',app.CurrentPlace.CurrentRegion])

                elseif app.BothButton.Value && app.CumulativeButton.Value

                    yyaxis (app.UIAxes,"left")
                    bar(app.UIAxes,app.Dates,app.CurrentPlace.CumCases,'blue');

                    yyaxis (app.UIAxes,"right")
                    plot(app.UIAxes,app.Dates,app.CurrentPlace.CumDeath,'red');
                    datetick(app.UIAxes,'x',"dd/mm/yy","keeplimits") ;
                    title(app.UIAxes,['Both cumulative cases and Death in ',app.CurrentPlace.CurrentRegion]);


                elseif app.BothButton.Value && app.DailyButton.Value


                    yyaxis (app.UIAxes,"left")
                    bar(app.UIAxes,app.Dates,app.CurrentPlace.DailyCases,'blue');

                    yyaxis (app.UIAxes,"right")
                    plot(app.UIAxes,app.Dates,app.CurrentPlace.DailyDeath,'red');
                    datetick(app.UIAxes,'x',"dd/mm/yy","keeplimits") ;
                    title(app.UIAxes,['Both Daily cases and Death in ',app.CurrentPlace.CurrentRegion]);

                end
            end
            grid(app.UIAxes,'on');
            app.UIAxes.YAxis(1).Exponent = 0;
            app.UIAxes.YAxis(1).TickLabelFormat = '%.0f';
            app.UIAxes.YAxis(1).Color = [0 0 0];
           

        end
        
        function CalcAverage(app)      %measuring the average when the slider change
            app.Average = Global ;
            app.Average.CumCases = movmean(app.CurrentPlace.CumCases,[app.AverageDays-1 , 0]);
            app.Average.CumDeath = movmean(app.CurrentPlace.CumDeath,[app.AverageDays-1 , 0]);
            app.Average.DailyCases = movmean(app.CurrentPlace.DailyCases,[app.AverageDays-1 , 0]);
            app.Average.DailyDeath = movmean(app.CurrentPlace.DailyDeath,[app.AverageDays-1 , 0]);
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load covid_data.mat covid_data
            app.World = Global ;
            app.World.Children = Global(covid_data) ;
            app.World = worldData(app.World);
            app.World = FillChild(app.World);
            Sortingcountries(app);
            fillDates(app,covid_data);
            app.CurrentPlace = app.World ;
            PlotData(app);


        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            app.Countries = app.CountryListBox.Value;
            app.StatesListBox.Items = {'All'};
            app.StatesListBox.Value = 'All';
            if isequal(app.Countries,app.World.CurrentRegion)
                app.CurrentPlace = app.World;
                app.DatatoplotButtonGroup.SelectedObject = app.CasesButton ;
                app.OptionsButtonGroup.SelectedObject = app.CumulativeButton ;
                app.AveragedofdaysSlider.Value = 1 ;
                app.AverageDays =1;
                PlotData(app);

            else
                for i = 1 : length(app.World.Children)
                    if isequal(app.Countries,app.World.Children(i).CurrentRegion)
                        break;
                    end
                end
                
                if ~isempty(app.World.Children(i).Children)
               
                    for j = 1 : length(app.World.Children(i).Children)
                        app.StatesListBox.Items(j+1) = {app.World.Children(i).Children(j).CurrentRegion} ;
                    end
                end
                app.DatatoplotButtonGroup.SelectedObject = app.CasesButton ;
                app.OptionsButtonGroup.SelectedObject = app.CumulativeButton ;
                app.CurrentPlace = app.World.Children(i);
                app.AveragedofdaysSlider.Value = 1 ;
                app.AverageDays =1;
                PlotData(app);
                app.Countries = app.CurrentPlace ;
            end
        end

        % Value changed function: StatesListBox
        function StatesListBoxValueChanged(app, event)
             app.CurrentPlace = app.Countries ;
            for i = 1 : length(app.CurrentPlace.Children)
                if isequal(app.CurrentPlace.Children(i).CurrentRegion,app.StatesListBox.Value)
                    app.CurrentPlace = app.CurrentPlace.Children(i) ;
                    break;
                end
            end
            app.DatatoplotButtonGroup.SelectedObject = app.CasesButton ;
            app.OptionsButtonGroup.SelectedObject = app.CumulativeButton ;
            app.AveragedofdaysSlider.Value = 1 ;
            app.AverageDays =1;
            PlotData(app)
            
        end

        % Selection changed function: DatatoplotButtonGroup
        function DatatoplotButtonGroupSelectionChanged(app, event)
            app.OptionsButtonGroup.SelectedObject = app.CumulativeButton ;
            app.AveragedofdaysSlider.Value = 1 ;
            app.AverageDays =1;
            PlotData(app)
            
        end

        % Selection changed function: OptionsButtonGroup
        function OptionsButtonGroupSelectionChanged(app, event)
            app.AveragedofdaysSlider.Value = 1 ;
            app.AverageDays =1;
            PlotData(app)
            
        end

        % Value changed function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanged(app, event)
            app.AverageDays = floor(app.AveragedofdaysSlider.Value); %rounding decimals
            CalcAverage(app) ;
            PlotData(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 10];
            app.CountryListBoxLabel = uilabel(app.UIFigure);
            app.CountryListBoxLabel.Text = 'Country';
            app.CountryListBox = uilistbox(app.UIFigure);
            app.CountryListBox.Items = {'Item 1', 'Item 5', 'Item 6', 'Item 3', 'Item 4'};
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.StatesListBoxLabel = uilabel(app.UIFigure);
            app.StatesListBoxLabel.Text = 'States';
            app.StatesListBox = uilistbox(app.UIFigure);
            app.StatesListBox.Items = {'All'};
            app.StatesListBox.ValueChangedFcn = createCallbackFcn(app, @StatesListBoxValueChanged, true);
            app.StatesListBox.Value = 'All';
            app.DatatoplotButtonGroup = uibuttongroup(app.UIFigure);
            app.DatatoplotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoplotButtonGroupSelectionChanged, true);
            app.DatatoplotButtonGroup.Title = 'Data to plot';
            app.CasesButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.CasesButton.Text = {'Cases'; ''};
            app.CasesButton.Value = true;
            app.DeathButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.DeathButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.BothButton.Text = 'Both';
            app.AveragedofdaysSliderLabel = uilabel(app.UIFigure);
            app.AveragedofdaysSliderLabel.Text = 'Averaged # of days';
            app.AveragedofdaysSlider = uislider(app.UIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.MajorTicks = [1 3 5 7 9 11 13 15];
            app.AveragedofdaysSlider.ValueChangedFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanged, true);
            app.AveragedofdaysSlider.Value = 1;
            app.OptionsButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionsButtonGroupSelectionChanged, true);
            app.OptionsButtonGroup.Title = 'Options';
            app.CumulativeButton = uiradiobutton(app.OptionsButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionsButtonGroup);
            app.DailyButton.Text = 'Daily';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = covid_19final_project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end